classdef ChangeTrackApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        ControlVariablesPanel       matlab.ui.container.Panel
        C001SpinnerLabel            matlab.ui.control.Label
        C001Spinner                 matlab.ui.control.Spinner
        C022Spinner_2Label          matlab.ui.control.Label
        C022Spinner                 matlab.ui.control.Spinner
        C026SpinnerLabel            matlab.ui.control.Label
        C026Spinner                 matlab.ui.control.Spinner
        C044SpinnerLabel            matlab.ui.control.Label
        C044Spinner                 matlab.ui.control.Spinner
        C053Spinner_2Label          matlab.ui.control.Label
        C053Spinner                 matlab.ui.control.Spinner
        C054SpinnerLabel            matlab.ui.control.Label
        C054Spinner                 matlab.ui.control.Spinner
        C076SpinnerLabel            matlab.ui.control.Label
        C076Spinner                 matlab.ui.control.Spinner
        C079SpinnerLabel            matlab.ui.control.Label
        C079Spinner                 matlab.ui.control.Spinner
        C087SpinnerLabel            matlab.ui.control.Label
        C087Spinner                 matlab.ui.control.Spinner
        C096Spinner_2Label          matlab.ui.control.Label
        C096Spinner                 matlab.ui.control.Spinner
        C107SpinnerLabel            matlab.ui.control.Label
        C107Spinner                 matlab.ui.control.Spinner
        C111SpinnerLabel            matlab.ui.control.Label
        C111Spinner                 matlab.ui.control.Spinner
        C112SpinnerLabel            matlab.ui.control.Label
        C112Spinner                 matlab.ui.control.Spinner
        C116SpinnerLabel            matlab.ui.control.Label
        C116Spinner                 matlab.ui.control.Spinner
        C122SpinnerLabel            matlab.ui.control.Label
        C122Spinner                 matlab.ui.control.Spinner
        C147SpinnerLabel            matlab.ui.control.Label
        C147Spinner                 matlab.ui.control.Spinner
        C154SpinnerLabel            matlab.ui.control.Label
        C154Spinner                 matlab.ui.control.Spinner
        C159SpinnerLabel            matlab.ui.control.Label
        C159Spinner                 matlab.ui.control.Spinner
        C218Spinner_2Label          matlab.ui.control.Label
        C218Spinner                 matlab.ui.control.Spinner
        C231SpinnerLabel            matlab.ui.control.Label
        C231Spinner                 matlab.ui.control.Spinner
        C233SpinnerLabel            matlab.ui.control.Label
        C233Spinner                 matlab.ui.control.Spinner
        C241SpinnerLabel            matlab.ui.control.Label
        C241Spinner                 matlab.ui.control.Spinner
        C247SpinnerLabel            matlab.ui.control.Label
        C247Spinner                 matlab.ui.control.Spinner
        C249SpinnerLabel            matlab.ui.control.Label
        C249Spinner                 matlab.ui.control.Spinner
        C251SpinnerLabel            matlab.ui.control.Label
        C251Spinner                 matlab.ui.control.Spinner
        C272SpinnerLabel            matlab.ui.control.Label
        C272Spinner                 matlab.ui.control.Spinner
        C276SpinnerLabel            matlab.ui.control.Label
        C276Spinner                 matlab.ui.control.Spinner
        C301SpinnerLabel            matlab.ui.control.Label
        C301Spinner                 matlab.ui.control.Spinner
        C306SpinnerLabel            matlab.ui.control.Label
        C306Spinner                 matlab.ui.control.Spinner
        C313Spinner_2Label          matlab.ui.control.Label
        C313Spinner                 matlab.ui.control.Spinner
        C314Spinner_2Label          matlab.ui.control.Label
        C314Spinner                 matlab.ui.control.Spinner
        C346SpinnerLabel            matlab.ui.control.Label
        C346Spinner                 matlab.ui.control.Spinner
        C353SpinnerLabel            matlab.ui.control.Label
        C353Spinner                 matlab.ui.control.Spinner
        RawMaterialPropertiesPanel  matlab.ui.container.Panel
        P356SpinnerLabel            matlab.ui.control.Label
        P356Spinner                 matlab.ui.control.Spinner
        P357SpinnerLabel            matlab.ui.control.Label
        P357Spinner                 matlab.ui.control.Spinner
        SampleNumberLabel           matlab.ui.control.Label
        SampleNumberEditField       matlab.ui.control.NumericEditField
        OptimizedOutputPanel        matlab.ui.container.Panel
        RONEditFieldLabel           matlab.ui.control.Label
        RONEditField                matlab.ui.control.NumericEditField
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        LossEditFieldLabel          matlab.ui.control.Label
        LossEditField               matlab.ui.control.NumericEditField
        SulfurEditField_2Label      matlab.ui.control.Label
        SulfurEditField             matlab.ui.control.EditField
    end

 
    methods (Access = public)
        
        function LoadData(app)
            global BestNetS
            global BestNetXW
            global PS_input
            global PS_output_XW
            global OptimizeFactor
            global Range
            
            tmp=load('OptimizeFactor.mat');
            OptimizeFactor=tmp.OptimizeFactor;
            
            tmp=load('BestNetS.mat');
            BestNetS=tmp.BestNetS;
            
            tmp=load('BestNetXW.mat');
            BestNetXW=tmp.BestNetXW;
            
            tmp=load('PS_input.mat');
            PS_input=tmp.PS_input;
            
            tmp=load('PS_output_XW.mat');
            PS_output_XW=tmp.PS_output_XW;
                        
            tmp=load('Range.mat'); 
            Range=tmp.Range; 
        end
        
        function [score,Product] = EvaluePerformance(app,controvec,...
                BestNetS,BestNetXW,PS_input,PS_output_XW)                        
            input = controvec;
            input_XW = mapminmax('apply',input,PS_input);             
            PS_output_XW_limit = PS_output_XW;            
            app.P356Spinner.Limits=[PS_output_XW.xmin 100];
            
            PS_output_XW_limit.xmax = app.P356Spinner.Value -0.2;
            PS_output_XW_limit.xrange = PS_output_XW_limit.xmax - PS_output_XW_limit.xmin;
            PS_output_XW_limit.gain = PS_output_XW_limit.yrange/PS_output_XW_limit.xrange; %还原时据此计算            
            PredicXW = mapminmax('reverse',BestNetXW(input_XW),PS_output_XW_limit);

            [~,PredicS] = max(BestNetS(input_XW));
            if PredicS == 2
                score = inf;
            else
                score = app.P356Spinner.Value-PredicXW;
            end
            Product=[PredicS*3+0.2,PredicXW];
        end
    end
    
    methods (Access = private)
        
        function results = GetValue(app,cc)
            global fixnums
            
            results=zeros(length(cc),1);
            othernums = int16(1:length(cc)); othernums(fixnums)=[];
            for i = othernums
                if cc(i)<100 && cc(i)>=10
                    strr=['aa=app.C0' num2str(cc(i)) 'Spinner.Value;'];
                    eval(strr); 
                    results(i)=aa;
                elseif cc(i)<10
                    strr=['aa=app.C00' num2str(cc(i)) 'Spinner.Value;'];
                    eval(strr); 
                    results(i)=aa;
                else
                    strr=['aa=app.C' num2str(cc(i)) 'Spinner.Value;'];
                    eval(strr); 
                    results(i)=aa;
                end  
            end
            for i = fixnums                
                strr=['aa=app.P' num2str(cc(i)) 'Spinner.Value;'];
                eval(strr); 
                results(i)=aa;
            end
        end
    
        function setValue(app,samp_i)
            
            global OptimizeFactor
            global cc
            global fixnums       
       
            othernums = int16(1:length(cc)); othernums(fixnums)=[];
            for i = othernums
                if cc(i)<100 && cc(i)>=10
                    strr=['app.C0' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(samp_i,i);'];
                    eval(strr);                    
                elseif cc(i)<10
                    strr=['app.C00' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(samp_i,i);'];
                    eval(strr);                   
                else
                    strr=['app.C' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(samp_i,i);'];
                    eval(strr);                  
                end                
            end            
        end
           
        function [Product_0,Product] = updateControlVar(app,samp_i)
            global BestNetS
            global BestNetXW
            global PS_input
            global PS_output_XW
            global OptimizeFactor             
            
            global cc
            global fixnums                                  
            
            if samp_i > 0 && samp_i < 325
                for i = fixnums                
                    strr=['app.P' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(samp_i,i);'];
                    eval(strr);
                end
                controvec = OptimizeFactor(samp_i,1:end-2)';          
                [~,Product_0] = EvaluePerformance(app,controvec,BestNetS,BestNetXW,PS_input,PS_output_XW);
            elseif samp_i == 0
                Product_0=[0,0];
            else
                controvec = GetValue(app,cc);            
                [~,Product_0] = EvaluePerformance(app,controvec,BestNetS,BestNetXW,PS_input,PS_output_XW);
            end
            
            controvec = GetValue(app,cc);            
            [~,Product] = EvaluePerformance(app,controvec,BestNetS,BestNetXW,PS_input,PS_output_XW);
        end
    
        function updateFrame(app,Product_0,Product)            
            global updateNum         
            
            app.RONEditField.Value=Product(2);
            app.LossEditField.Value=app.P356Spinner.Value-Product(2);
            if Product(2)>=95
                app.Lamp.Color=[0,1,0];
            elseif Product(2)<89
                app.Lamp.Color=[1,0,0];
            else
                app.Lamp.Color=[0,1,0];
            end
            
            if Product(1)>10
                app.SulfurEditField.Value=['>10' char(956) 'g/g'];
                app.Lamp_2.Color=[1,0,0];
            elseif Product(1)<5
                app.SulfurEditField.Value=['<5' char(956) 'g/g'];
                app.Lamp_2.Color=[0,1,0];
            else
                app.SulfurEditField.Value=['>5' char(956) 'g/g'];
                app.Lamp_2.Color=[1,0,0];
            end
            
            X = categorical({'Sample','Current'});
                                   
            b=bar(app.UIAxes2,X,[Product_0(2) Product(2)],'FaceColor','flat');
            b.CData(2,:)=[1 0 1];
            b.CData(1,:)=[1 0 0];
            b.BarWidth = 0.5;
            app.UIAxes2.YLabel.String='RON';
            app.UIAxes2.XLabel.Visible='off';
            app.UIAxes2.Title.Visible='off'; 
            ylim(app.UIAxes2,[85 100]);
            %view(app.UIAxes2,[90 90])        
            
            if updateNum<150
                xlim(app.UIAxes3,'auto');
            elseif updateNum>=150 && updateNum<5000            
                xlim(app.UIAxes3,[updateNum-149 updateNum]); 
            else
                cla(app.UIAxes3,'reset');
                updateNum=1;                
            end
            yyaxis(app.UIAxes3, 'left')
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,updateNum,Product(2),'ro','MarkerFaceColor','r','MarkerSize',3.5)
            xlabel(app.UIAxes3, 'Historical trajectories')
            ylabel(app.UIAxes3, 'RON')
            set(app.UIAxes3,'YColor',[1 0 0])
            app.UIAxes3.Title.Visible='off';
            
            yyaxis(app.UIAxes3, 'right')
            hold(app.UIAxes3,'on')            
            if Product(1)<5
                plot(app.UIAxes3,updateNum,Product(1),'bv','MarkerSize',3.5)   
            else
                plot(app.UIAxes3,updateNum,Product(1),'b^','MarkerSize',3.5) 
            end
            ylabel(app.UIAxes3, 'Sulfur content (\mug/g)')
            ylim(app.UIAxes3,[2 7.4]);            
            set(app.UIAxes3, 'YTick', [3.2 6.2]);
            set(app.UIAxes3, 'TickLength', [0.001 0.025]);
            set(app.UIAxes3, 'YTickLabel', {'<5' '>5'});
            set(app.UIAxes3,'YColor',[0 0 1])            
        end
    end   
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global;
                        
            global cc
            cc=[356,111,353,112,306,116,233,357,26,301,122,231,107,44,54,79,276,346,...
                251,87,22,159,147,249,76,272,154,1,96,241,218,313,314,53,247];
            
            global fixnums
            fixnums = [1,8];
            
            global updateNum
            updateNum=1;
            
            global OptimizeFactor
            global Range
            
            LoadData(app);                       
            samp_i=133;
            app.SampleNumberEditField.Value=samp_i;
            
            for i = fixnums                
                strr=['app.P' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(samp_i,i);'];
                eval(strr); 
            end              
            app.P356Spinner.Step=0.1; app.P356Spinner.Limits=[85 92];
            app.P357Spinner.Step=0.04; app.P357Spinner.Limits=[43 64];
            
            othernums = int16(1:length(cc)); othernums(fixnums)=[];
            for i = othernums
                if cc(i)<100 && cc(i)>=10
                    strr=['app.C0' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(133,i);'];
                    eval(strr);
                    strr=['app.C0' num2str(cc(i)) 'Spinner.Step=Range(3,i);'];
                    eval(strr);
                    strr=['app.C0' num2str(cc(i)) 'Spinner.Limits=[Range(1,i) Range(2,i)];'];
                    eval(strr);
                elseif cc(i)<10
                    strr=['app.C00' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(133,i);'];
                    eval(strr);
                    strr=['app.C00' num2str(cc(i)) 'Spinner.Step=Range(3,i);'];
                    eval(strr);
                    strr=['app.C00' num2str(cc(i)) 'Spinner.Limits=[Range(1,i) Range(2,i)];'];
                    eval(strr);
                else
                    strr=['app.C' num2str(cc(i)) 'Spinner.Value=OptimizeFactor(133,i);'];
                    eval(strr);
                    strr=['app.C' num2str(cc(i)) 'Spinner.Step=Range(3,i);'];
                    eval(strr);
                    strr=['app.C' num2str(cc(i)) 'Spinner.Limits=[Range(1,i) Range(2,i)];'];
                    eval(strr);
                end                
            end           
            [Product_0,Product] = updateControlVar(app,samp_i);
            updateFrame(app,Product_0,Product);
        end

        % Callback function: C001Spinner, C022Spinner, C026Spinner, 
        % C044Spinner, C053Spinner, C054Spinner, C076Spinner, 
        % C079Spinner, C087Spinner, C096Spinner, C107Spinner, 
        % C111Spinner, C112Spinner, C116Spinner, C122Spinner, 
        % C147Spinner, C154Spinner, C159Spinner, C218Spinner, 
        % C231Spinner, C233Spinner, C241Spinner, C247Spinner, 
        % C249Spinner, C251Spinner, C272Spinner, C276Spinner, 
        % C301Spinner, C306Spinner, C313Spinner, C314Spinner, 
        % C346Spinner, C353Spinner
        function C026SpinnerValueChanged(app, event)
            global updateNum
            updateNum=updateNum+1; 
            samp_i=int16(app.SampleNumberEditField.Value);
            [Product_0,Product] = updateControlVar(app,samp_i);
            updateFrame(app,Product_0,Product);          
        end

        % Value changed function: P356Spinner, P357Spinner
        function RawEditFieldValueChanged(app, event)
            global updateNum
            updateNum=updateNum+1;
            app.SampleNumberEditField.Value=0; 
            samp_i=int16(app.SampleNumberEditField.Value);
            [Product_0,Product] = updateControlVar(app,samp_i);
            updateFrame(app,Product_0,Product);     
        end

        % Value changed function: SampleNumberEditField
        function SampleNumberEditFieldValueChanged(app, event)
            global updateNum
            updateNum=updateNum+1;
            samp_i=round(app.SampleNumberEditField.Value);            
            if samp_i <1 && samp_i>325
                samp_i=133;
                app.SampleNumberEditField.Value=133;
            else
                app.SampleNumberEditField.Value=samp_i;
            end
            setValue(app,samp_i);            
            [Product_0,Product] = updateControlVar(app,samp_i);
            updateFrame(app,Product_0,Product); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ControlVariablesPanel = uipanel(app.UIFigure);
            app.ControlVariablesPanel.Title = 'Control Variables';
            app.C001SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C001SpinnerLabel.Text = 'C001';
            app.C001Spinner = uispinner(app.ControlVariablesPanel);
            app.C001Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C001Spinner.Value = 19;
            app.C022Spinner_2Label = uilabel(app.ControlVariablesPanel);
            app.C022Spinner_2Label.Text = 'C022';
            app.C022Spinner = uispinner(app.ControlVariablesPanel);
            app.C022Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C022Spinner.Value = 19;
            app.C026SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C026SpinnerLabel.Text = 'C026';
            app.C026Spinner = uispinner(app.ControlVariablesPanel);
            app.C026Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C026Spinner.Value = 19;
            app.C044SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C044SpinnerLabel.Text = 'C044';
            app.C044Spinner = uispinner(app.ControlVariablesPanel);
            app.C044Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C044Spinner.Value = 19;
            app.C053Spinner_2Label = uilabel(app.ControlVariablesPanel);
            app.C053Spinner_2Label.Text = 'C053';
            app.C053Spinner = uispinner(app.ControlVariablesPanel);
            app.C053Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C053Spinner.Value = 19;
            app.C054SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C054SpinnerLabel.Text = 'C054';
            app.C054Spinner = uispinner(app.ControlVariablesPanel);
            app.C054Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C054Spinner.Value = 19;
            app.C076SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C076SpinnerLabel.Text = 'C076';
            app.C076Spinner = uispinner(app.ControlVariablesPanel);
            app.C076Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C076Spinner.Value = 19;
            app.C079SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C079SpinnerLabel.Text = 'C079';
            app.C079Spinner = uispinner(app.ControlVariablesPanel);
            app.C079Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C079Spinner.Value = 19;
            app.C087SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C087SpinnerLabel.Text = 'C087';
            app.C087Spinner = uispinner(app.ControlVariablesPanel);
            app.C087Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C087Spinner.Value = 19;
            app.C096Spinner_2Label = uilabel(app.ControlVariablesPanel);
            app.C096Spinner_2Label.Text = 'C096';
            app.C096Spinner = uispinner(app.ControlVariablesPanel);
            app.C096Spinner.ValueDisplayFormat = '%11.2g';
            app.C096Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C096Spinner.Value = 19;
            app.C107SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C107SpinnerLabel.Text = 'C107';
            app.C107Spinner = uispinner(app.ControlVariablesPanel);
            app.C107Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C107Spinner.Value = 19;
            app.C111SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C111SpinnerLabel.Text = 'C111';
            app.C111Spinner = uispinner(app.ControlVariablesPanel);
            app.C111Spinner.ValueDisplayFormat = '%11.2g';
            app.C111Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C111Spinner.Value = 19;
            app.C112SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C112SpinnerLabel.Text = 'C112';
            app.C112Spinner = uispinner(app.ControlVariablesPanel);
            app.C112Spinner.ValueDisplayFormat = '%11.2g';
            app.C112Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C112Spinner.Value = 19;
            app.C116SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C116SpinnerLabel.Text = 'C116';
            app.C116Spinner = uispinner(app.ControlVariablesPanel);
            app.C116Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C116Spinner.Value = 19;
            app.C122SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C122SpinnerLabel.Text = 'C122';
            app.C122Spinner = uispinner(app.ControlVariablesPanel);
            app.C122Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C122Spinner.Value = 19;
            app.C147SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C147SpinnerLabel.Text = 'C147';
            app.C147Spinner = uispinner(app.ControlVariablesPanel);
            app.C147Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C147Spinner.Value = 19;
            app.C154SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C154SpinnerLabel.Text = 'C154';
            app.C154Spinner = uispinner(app.ControlVariablesPanel);
            app.C154Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C154Spinner.Value = 19;
            app.C159SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C159SpinnerLabel.Text = 'C159';
            app.C159Spinner = uispinner(app.ControlVariablesPanel);
            app.C159Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C159Spinner.Value = 19;
            app.C218Spinner_2Label = uilabel(app.ControlVariablesPanel);
            app.C218Spinner_2Label.Text = 'C218';
            app.C218Spinner = uispinner(app.ControlVariablesPanel);
            app.C218Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C218Spinner.Value = 19;
            app.C231SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C231SpinnerLabel.Text = 'C231';
            app.C231Spinner = uispinner(app.ControlVariablesPanel);
            app.C231Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C231Spinner.Value = 19;
            app.C233SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C233SpinnerLabel.Text = 'C233';
            app.C233Spinner = uispinner(app.ControlVariablesPanel);
            app.C233Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C233Spinner.Value = 19;
            app.C241SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C241SpinnerLabel.Text = 'C241';
            app.C241Spinner = uispinner(app.ControlVariablesPanel);
            app.C241Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C241Spinner.Value = 19;
            app.C247SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C247SpinnerLabel.Text = 'C247';
            app.C247Spinner = uispinner(app.ControlVariablesPanel);
            app.C247Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C247Spinner.Value = 19;
            app.C249SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C249SpinnerLabel.Text = 'C249';
            app.C249Spinner = uispinner(app.ControlVariablesPanel);
            app.C249Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C249Spinner.Value = 19;
            app.C251SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C251SpinnerLabel.Text = 'C251';
            app.C251Spinner = uispinner(app.ControlVariablesPanel);
            app.C251Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C251Spinner.Value = 19;
            app.C272SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C272SpinnerLabel.Text = 'C272';
            app.C272Spinner = uispinner(app.ControlVariablesPanel);
            app.C272Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C272Spinner.Value = 19;
            app.C276SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C276SpinnerLabel.Text = 'C276';
            app.C276Spinner = uispinner(app.ControlVariablesPanel);
            app.C276Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C276Spinner.Value = 19;
            app.C301SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C301SpinnerLabel.Text = 'C301';
            app.C301Spinner = uispinner(app.ControlVariablesPanel);
            app.C301Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C301Spinner.Value = 19;
            app.C306SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C306SpinnerLabel.Text = 'C306';
            app.C306Spinner = uispinner(app.ControlVariablesPanel);
            app.C306Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C306Spinner.Value = 19;
            app.C313Spinner_2Label = uilabel(app.ControlVariablesPanel);
            app.C313Spinner_2Label.Text = 'C313';
            app.C313Spinner = uispinner(app.ControlVariablesPanel);
            app.C313Spinner.ValueChangingFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C313Spinner.Value = 19;
            app.C314Spinner_2Label = uilabel(app.ControlVariablesPanel);
            app.C314Spinner_2Label.Text = 'C314';
            app.C314Spinner = uispinner(app.ControlVariablesPanel);
            app.C314Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C314Spinner.Value = 19;
            app.C346SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C346SpinnerLabel.Text = 'C346';
            app.C346Spinner = uispinner(app.ControlVariablesPanel);
            app.C346Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C346Spinner.Value = 19;
            app.C353SpinnerLabel = uilabel(app.ControlVariablesPanel);
            app.C353SpinnerLabel.Text = 'C353';
            app.C353Spinner = uispinner(app.ControlVariablesPanel);
            app.C353Spinner.ValueDisplayFormat = '%11.2g';
            app.C353Spinner.ValueChangedFcn = createCallbackFcn(app, @C026SpinnerValueChanged, true);
            app.C353Spinner.Value = 19;
            app.RawMaterialPropertiesPanel = uipanel(app.UIFigure);
            app.RawMaterialPropertiesPanel.Title = 'Raw Material Properties';
            app.P356SpinnerLabel = uilabel(app.RawMaterialPropertiesPanel);
            app.P356SpinnerLabel.Text = 'P356';
            app.P356Spinner = uispinner(app.RawMaterialPropertiesPanel);
            app.P356Spinner.ValueChangedFcn = createCallbackFcn(app, @RawEditFieldValueChanged, true);
            app.P356Spinner.Value = 19;
            app.P357SpinnerLabel = uilabel(app.RawMaterialPropertiesPanel);
            app.P357SpinnerLabel.Text = 'P357';
            app.P357Spinner = uispinner(app.RawMaterialPropertiesPanel);
            app.P357Spinner.ValueChangedFcn = createCallbackFcn(app, @RawEditFieldValueChanged, true);
            app.P357Spinner.Value = 19;
            app.SampleNumberLabel = uilabel(app.UIFigure);
            app.SampleNumberLabel.Text = 'Sample Number:';
            app.SampleNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleNumberEditField.Limits = [0 325];
            app.SampleNumberEditField.ValueDisplayFormat = '%.0f';
            app.SampleNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SampleNumberEditFieldValueChanged, true);
            app.SampleNumberEditField.Value = 133;
            app.OptimizedOutputPanel = uipanel(app.UIFigure);
            app.OptimizedOutputPanel.Title = 'Optimized Output';
            app.RONEditFieldLabel = uilabel(app.OptimizedOutputPanel);
            app.RONEditFieldLabel.Text = 'RON';
            app.RONEditField = uieditfield(app.OptimizedOutputPanel, 'numeric');
            app.RONEditField.Value = 95;
            app.Lamp = uilamp(app.OptimizedOutputPanel);
            app.Lamp_2 = uilamp(app.OptimizedOutputPanel);
            app.LossEditFieldLabel = uilabel(app.OptimizedOutputPanel);
            app.LossEditFieldLabel.Text = 'Loss';
            app.LossEditField = uieditfield(app.OptimizedOutputPanel, 'numeric');
            app.LossEditField.Value = 0.2;
            app.SulfurEditField_2Label = uilabel(app.OptimizedOutputPanel);
            app.SulfurEditField_2Label.Text = 'Sulfur';
            app.SulfurEditField = uieditfield(app.OptimizedOutputPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ChangeTrackApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end